classdef advance_sound < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        AudioFilesPanel   matlab.ui.container.Panel
        TextArea          matlab.ui.control.TextArea
        PlaylistPanel     matlab.ui.container.Panel
        listbox_playlist  matlab.ui.control.ListBox
        AddButton         matlab.ui.control.Button
        PlayButton        matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function start(app)
           
            app.UIFigure.Position = [ 0 0 1546 833];
  global liste files listboxItems file data k pth;
                disp(cd);
                disp(cd);
  
       %  pth = 'C:\Users\malik\Desktop\MATLAB FILES\Assigment1\';
       
            liste = dir(strcat(cd,'\playlist\*.wav'));
            
            % liste = dir('pth','*.wav');
            disp(liste(1));
           
            app.TextArea.Value=liste(1).name;
                
                for k = 1 : numel(liste)
      listboxItems{k} = liste(k).name;
     app.TextArea.Value=liste(k).name;
     disp(listboxItems{k});
                end
    app.listbox_playlist.Items=listboxItems;

        end

        % Button pushed function: AddButton
        function create_playlist(app, event)
          global listboxItems snd FS filename pathname full_path_playlist player;
             [filename, pathname]=uigetfile({'*.wav'},'File Selector');
       %  [filename, pathname]=uigetfile('*.wav','Select the INPUT DATA FILE(s)','MultiSelect','on');
         full_path_playlist=strcat(pathname,filename);
            app.TextArea.Value=full_path_playlist;
            
             % for k = 1 : numel(full_path_playlist)
                 % filename = 'handel.wav';
                 % audiowrite(filename,y,Fs);
             % listboxItems(k) = full_path_playlist(k);
             % app.listbox_playlist.Items=listboxItems;
             [snd,FS]=audioread(full_path_playlist);
              filename = 'handel.wav';
                 audiowrite(filename,y,Fs);
           
              end
   
        end

        % Button pushed function: PlayButton
        function play_playlist(app, event)
            global snd1 FS1 liste player1 value;
            value = app.listbox_playlist.Value;
            app.TextArea.Value=value;
             liste = strcat(cd,'\playlist\',value);
      
            [snd1,FS1]=audioread(liste);
              player1 = audioplayer(snd1, FS1);
              play(player1);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AudioFilesPanel = uipanel(app.UIFigure);
            app.AudioFilesPanel.Title = 'Audio Files';
            app.TextArea = uitextarea(app.AudioFilesPanel);
            app.PlaylistPanel = uipanel(app.UIFigure);
            app.PlaylistPanel.Title = 'Playlist';
            app.listbox_playlist = uilistbox(app.PlaylistPanel);
            app.listbox_playlist.Items = {};
            app.listbox_playlist.Value = {};
            app.AddButton = uibutton(app.PlaylistPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @create_playlist, true);
            app.AddButton.Text = 'Add';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @play_playlist, true);
            app.PlayButton.Text = 'Play';
        end
    end

    methods (Access = public)

        % Construct app
        function app = advance_sound

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @start)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end