classdef mixing_sounds < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        first_sound_graph     matlab.ui.control.UIAxes
        Sound1Panel           matlab.ui.container.Panel
        BrowseButton          matlab.ui.control.Button
        AudioAddressLabel     matlab.ui.control.Label
        sound1_file_address   matlab.ui.control.TextArea
        PlayButton            matlab.ui.control.Button
        PauseButton           matlab.ui.control.Button
        ResumeButton          matlab.ui.control.Button
        StopButton            matlab.ui.control.Button
        Sound2Panel           matlab.ui.container.Panel
        AudioAddressLabel_2   matlab.ui.control.Label
        sound2_file_address   matlab.ui.control.TextArea
        BrowseButton_2        matlab.ui.control.Button
        PlayButton_2          matlab.ui.control.Button
        PauseButton_2         matlab.ui.control.Button
        ResumeButton_2        matlab.ui.control.Button
        StopButton_2          matlab.ui.control.Button
        second_sound_graph    matlab.ui.control.UIAxes
        Sound1AmplifierPanel  matlab.ui.container.Panel
        sound1_volume_slider  matlab.ui.control.Slider
        SpeedLabel            matlab.ui.control.Label
        speed_sound1_slider   matlab.ui.control.Slider
        vol1_btn              matlab.ui.control.Button
        Sound2AmplifierPanel  matlab.ui.container.Panel
        sound2_volume_slider  matlab.ui.control.Slider
        SpeedLabel_2          matlab.ui.control.Label
        speed_sound2_slider   matlab.ui.control.Slider
        vol2_btn              matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function start(app)
           app.UIFigure.Position = [0 0 1546 833];
         %  movegui(center)
        end

        % Button pushed function: BrowseButton
        function browse_sound1(app, event)
                global  filename pathname full_path_sound1;
              
           [filename, pathname]=uigetfile({'*.wav'},'File Selector');
            full_path_sound1=strcat(pathname,filename);
            app.sound1_file_address.Value=full_path_sound1;


        end

        % Button pushed function: PlayButton
        function play_sound1(app, event)
        global snd1 FS1 full_path_sound1 player1;
           app.PauseButton.Enable = 'on';
            app.ResumeButton.Enable = 'on';
             
              if isempty(full_path_sound1)
              message = sprintf('File hazard! Sound 1 Path Is Empty.');
              uialert(app.UIFigure  ,message,'Error',...
                'Icon','error');
              else
              [snd1,FS1]=audioread(full_path_sound1);
              player1 = audioplayer(snd1, FS1);
              play(player1);
               showgraphfirst(app,snd1,1);
              end
      % audio_setting(app);
        end

        % Value changed function: sound1_volume_slider
        function volume_sound1(app, event)
             global snd1 FS1 full_path_sound1 player1 ;
           snd_value= get(app.sound1_volume_slider,'value');
           speed_snd1= get(app.speed_sound1_slider,'value');
        %  app.sound1_volume.Value=snd_value;
          pause(player1);
          NewStart=get(player1,'CurrentSample')+1;
         stop(player1);
          [snd1,FS1]=audioread(full_path_sound1);
          snd1=snd1(NewStart:end,:); 
           player1=audioplayer(snd1*snd_value,speed_snd1*FS1);
           play(player1);
           showgraphfirst(app,snd1,1);
            
        end

        % Button pushed function: BrowseButton_2
        function browse_sound2(app, event)
              global  filename2 pathname2 full_path_sound2;
              
           [filename2, pathname2]=uigetfile({'*.wav'},'File Selector');
            full_path_sound2=strcat(pathname2,filename2);
            app.sound2_file_address.Value=full_path_sound2;
        end

        % Button pushed function: PlayButton_2
        function play_sound2(app, event)
             global snd2 FS2 full_path_sound2 player2;
             app.PauseButton_2.Enable = 'on';
            app.ResumeButton_2.Enable = 'on';
            app.StopButton_2.Enable = 'on';
             
              if isempty(full_path_sound2)
              message = sprintf('File hazard! Sound 2 Path Is Empty.');
              uialert(app.UIFigure  ,message,'Error',...
                'Icon','error');
              else
              [snd2,FS2]=audioread(full_path_sound2);
              player2 = audioplayer(snd2, FS2);
            %  sound(snd2,FS);
             play(player2);
            showgraphfirst(app,snd2,2);
              end
        end

        % Button pushed function: PauseButton
        function pause_sound1(app, event)
            global player1;
             pause(player1);
            
        end

        % Button pushed function: ResumeButton
        function resume_sound1(app, event)
             global player1;
             resume(player1);
        end

        % Value changed function: sound2_volume_slider
        function volume_sound2(app, event)
          %  value = app.sound2_volume_slider.Value;
              global snd2 FS2 full_path_sound2 player2 ;
           snd_value2= get(app.sound2_volume_slider,'value');
           speed_snd2=get(app.speed_sound2_slider,'value');
        %  app.sound1_volume.Value=snd_value;
          pause(player2);
          NewStart=get(player2,'CurrentSample')+1;
         stop(player2);
          [snd2,FS2]=audioread(full_path_sound2);
          snd2=snd2(NewStart:end,:); 
           player2=audioplayer(snd2*snd_value2,speed_snd2*FS2);
           play(player2);
           showgraphfirst(app,snd2,2);
        end

        % Callback function
        function mix_sounds(app, event)
             global snd1 snd2 FS full_path_sound1 full_path_sound2 player3;
             
              if isempty(full_path_sound2 )
              message = sprintf('File hazard!Sound 1 or Sound 2 Path Is Empty.');
              uialert(app.UIFigure  ,message,'Error',...
                'Icon','error');
              else
              [snd1,FS]=audioread(full_path_sound1);
              [snd2,FS]=audioread(full_path_sound2);
              merge_audio = [snd1;snd2];
        
              player3 = audioplayer(merge_audio,FS);
             play(player3);
          
            showgraphfirst(app,merge_audio,3);
              end
        end

        % Value changed function: speed_sound1_slider
        function speed_sound1(app, event)
          %  value = app.speed_sound1_slider.Value;
            
                   %  value = app.sound2_volume_slider.Value;
              global snd1 FS1 full_path_sound1 player1 ;
           speed_snd1= get(app.speed_sound1_slider,'value');
            snd_value1= fix(get(app.sound1_volume_slider,'value'));
          pause(player1);
          NewStart=get(player1,'CurrentSample')+1;
         stop(player1);
          [snd1,FS1]=audioread(full_path_sound1);
          snd1=snd1(NewStart:end,:); 
           player1=audioplayer(snd1*snd_value1,speed_snd1*FS1);
           play(player1);
           showgraphfirst(app,snd1,1);
            
        end

        % Value changed function: speed_sound2_slider
        function speed_sound2(app, event)
   
              global snd2 FS2 full_path_sound2 player2 ;
           speed_snd2=get(app.speed_sound2_slider,'value');
           snd_value2= fix(get(app.sound2_volume_slider,'value'));
          pause(player2);
          NewStart=get(player2,'CurrentSample')+1;
         stop(player2);
          [snd2,FS2]=audioread(full_path_sound2);
          snd2=snd2(NewStart:end,:); 
           player2=audioplayer(snd2*snd_value2,speed_snd2*FS2);
           play(player2);
           showgraphfirst(app,snd2,2);
            
        end

        % Callback function
        function volume_sound_mix(app, event)
         
             %  value = app.sound2_volume_slider.Value;
              global snd_mix snd1 snd2 FS FS_mix full_path_sound1 full_path_sound2 player3 ;
           snd_value_mix= get(app.soundmix_volume_slider,'value');
           speed_snd_mix=get(app.speed_soundmix_slider,'value');
       
          pause(player3);
          NewStart=get(player3,'CurrentSample')+1;
          stop(player3);
         
          [snd1,FS]=audioread(full_path_sound1);
          [snd2,FS]=audioread(full_path_sound2);
              snd_mix = [snd1;snd2];
              
         
          snd_mix=snd_mix(NewStart:end,:); 
           player3=audioplayer(snd_mix*snd_value_mix,speed_snd_mix*FS);
           play(player3);
           showgraphfirst(app,snd_mix,3);
        end

        % Callback function
        function speed_sound_mix(app, event)
             global snd_mix snd1 snd2 FS FS_mix full_path_sound1 full_path_sound2 player3 ;
           snd_value_mix= get(app.soundmix_volume_slider,'value');
           speed_snd_mix=get(app.speed_soundmix_slider,'value');
       
          pause(player3);
          NewStart=get(player3,'CurrentSample')+1;
          stop(player3);
         
          [snd1,FS]=audioread(full_path_sound1);
          [snd2,FS]=audioread(full_path_sound2);
              snd_mix = [snd1;snd2];
              
         
          snd_mix=snd_mix(NewStart:end,:); 
           player3=audioplayer(snd_mix*snd_value_mix,speed_snd_mix*FS);
           play(player3);
           showgraphfirst(app,snd_mix,3);
        end

        % Button pushed function: PauseButton_2
        function sound2_pause(app, event)
             global player2;
            pause(player2);
        end

        % Callback function
        function pause_soundmix(app, event)
            global player3;
            pause(player3);
        end

        % Button pushed function: ResumeButton_2
        function resume_sound2(app, event)
            global player2;
            resume(player2);
        end

        % Callback function
        function resume_soundmix(app, event)
            global player3;
            resume(player3);
        end

        % Close request function: UIFigure
        function close_fnc(app, event)
             delete(app);
            clc;
            clear all;
           
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @close_fnc, true);
            app.first_sound_graph = uiaxes(app.UIFigure);
            title(app.first_sound_graph, 'Sound 1 Plot')
            xlabel(app.first_sound_graph, 'X')
            ylabel(app.first_sound_graph, 'Y')
            app.Sound1Panel = uipanel(app.UIFigure);
            app.Sound1Panel.Title = 'Sound 1';
            app.BrowseButton = uibutton(app.Sound1Panel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @browse_sound1, true);
            app.BrowseButton.Text = 'Browse';
            app.AudioAddressLabel = uilabel(app.Sound1Panel);
            app.AudioAddressLabel.Text = 'Audio Address';
            app.sound1_file_address = uitextarea(app.Sound1Panel);
            app.PlayButton = uibutton(app.Sound1Panel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @play_sound1, true);
            app.PlayButton.Icon = 'play_btn.png';
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.Sound1Panel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @pause_sound1, true);
            app.PauseButton.Enable = 'off';
            app.PauseButton.Text = 'Pause';
            app.ResumeButton = uibutton(app.Sound1Panel, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @resume_sound1, true);
            app.ResumeButton.Enable = 'off';
            app.ResumeButton.Text = 'Resume';
            app.StopButton = uibutton(app.Sound1Panel, 'push');
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.Sound2Panel = uipanel(app.UIFigure);
            app.Sound2Panel.Title = 'Sound 2';
            app.AudioAddressLabel_2 = uilabel(app.Sound2Panel);
            app.AudioAddressLabel_2.Text = 'Audio Address';
            app.sound2_file_address = uitextarea(app.Sound2Panel);
            app.BrowseButton_2 = uibutton(app.Sound2Panel, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @browse_sound2, true);
            app.BrowseButton_2.Text = 'Browse';
            app.PlayButton_2 = uibutton(app.Sound2Panel, 'push');
            app.PlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @play_sound2, true);
            app.PlayButton_2.Icon = 'play_btn.png';
            app.PlayButton_2.Text = 'Play';
            app.PauseButton_2 = uibutton(app.Sound2Panel, 'push');
            app.PauseButton_2.ButtonPushedFcn = createCallbackFcn(app, @sound2_pause, true);
            app.PauseButton_2.Enable = 'off';
            app.PauseButton_2.Text = 'Pause';
            app.ResumeButton_2 = uibutton(app.Sound2Panel, 'push');
            app.ResumeButton_2.ButtonPushedFcn = createCallbackFcn(app, @resume_sound2, true);
            app.ResumeButton_2.Enable = 'off';
            app.ResumeButton_2.Text = 'Resume';
            app.StopButton_2 = uibutton(app.Sound2Panel, 'push');
            app.StopButton_2.Enable = 'off';
            app.StopButton_2.Text = 'Stop';
            app.second_sound_graph = uiaxes(app.UIFigure);
            title(app.second_sound_graph, 'Sound 2 Plot')
            xlabel(app.second_sound_graph, 'X')
            ylabel(app.second_sound_graph, 'Y')
            app.Sound1AmplifierPanel = uipanel(app.UIFigure);
            app.Sound1AmplifierPanel.Title = 'Sound 1 Amplifier';
            app.sound1_volume_slider = uislider(app.Sound1AmplifierPanel);
            app.sound1_volume_slider.Limits = [0 10];
            app.sound1_volume_slider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.sound1_volume_slider.MajorTickLabels = {'', '1', '2', '3', '4', '5', '6', '7', '8', '9', ''};
            app.sound1_volume_slider.ValueChangedFcn = createCallbackFcn(app, @volume_sound1, true);
            app.sound1_volume_slider.MinorTicks = [];
            app.sound1_volume_slider.Value = 5;
            app.SpeedLabel = uilabel(app.Sound1AmplifierPanel);
            app.SpeedLabel.Text = 'Speed';
            app.speed_sound1_slider = uislider(app.Sound1AmplifierPanel);
            app.speed_sound1_slider.Limits = [0 4];
            app.speed_sound1_slider.MajorTickLabels = {'', '0.4', '0.8', '1.2', '1.6', '2', '2.4', '2.8', '3.2', '3.6', ''};
            app.speed_sound1_slider.ValueChangedFcn = createCallbackFcn(app, @speed_sound1, true);
            app.speed_sound1_slider.MinorTicks = [];
            app.speed_sound1_slider.Value = 0.9;
            app.vol1_btn = uibutton(app.Sound1AmplifierPanel, 'push');
            app.vol1_btn.Icon = 'voll.png';
            app.vol1_btn.Text = '';
            app.Sound2AmplifierPanel = uipanel(app.UIFigure);
            app.Sound2AmplifierPanel.Title = 'Sound 2 Amplifier';
            app.sound2_volume_slider = uislider(app.Sound2AmplifierPanel);
            app.sound2_volume_slider.Limits = [0 10];
            app.sound2_volume_slider.ValueChangedFcn = createCallbackFcn(app, @volume_sound2, true);
            app.sound2_volume_slider.MinorTicks = [];
            app.sound2_volume_slider.Value = 5;
            app.SpeedLabel_2 = uilabel(app.Sound2AmplifierPanel);
            app.SpeedLabel_2.Text = 'Speed';
            app.speed_sound2_slider = uislider(app.Sound2AmplifierPanel);
            app.speed_sound2_slider.Limits = [0 4];
            app.speed_sound2_slider.MajorTickLabels = {'0', '0.4', '0.8', '1.2', '1.6', '2', '2.4', '2.8', '3.2', '3.6', '4'};
            app.speed_sound2_slider.ValueChangedFcn = createCallbackFcn(app, @speed_sound2, true);
            app.speed_sound2_slider.MinorTicks = [];
            app.speed_sound2_slider.Value = 0.9;
            app.vol2_btn = uibutton(app.Sound2AmplifierPanel, 'push');
            app.vol2_btn.Icon = 'voll.png';
            app.vol2_btn.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mixing_sounds

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @start)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end