classdef playlist_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        CurrentPlaylistPanel  matlab.ui.container.Panel
        listbox_playlist      matlab.ui.control.ListBox
        ADDButton             matlab.ui.control.Button
        REMOVEButton          matlab.ui.control.Button
        SongNamesLabel        matlab.ui.control.Label
        MusicPlayerPanel      matlab.ui.container.Panel
        img                   matlab.ui.control.UIAxes
        PAUSEButton           matlab.ui.control.Button
        PLAYButton            matlab.ui.control.Button
        RESUMEButton          matlab.ui.control.Button
        SpeedSliderLabel      matlab.ui.control.Label
        SpeedSlider           matlab.ui.control.Slider
        Slider_2Label         matlab.ui.control.Label
        VolumeSlider          matlab.ui.control.Slider
        image_xs              matlab.ui.control.UIAxes
        DurationSliderLabel   matlab.ui.control.Label
        Slider                matlab.ui.control.Slider
        STOPButton            matlab.ui.control.Button
        image_xs_2            matlab.ui.control.UIAxes
        repeat                matlab.ui.control.Button
        mute_btn              matlab.ui.control.Button
        one_lbl               matlab.ui.control.Label
        Button                matlab.ui.control.Button
        Button_2              matlab.ui.control.Button
        AdvanceOptionsPanel   matlab.ui.container.Panel
        ReverseAudioButton    matlab.ui.control.Button
        ExitButton            matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function start_program(app)
            
            app.UIFigure.Position = [ 0 0 1546 833];
            
            
            global liste listboxItems k repeat_var vol_var reverse pre_next;
            repeat_var =1;
            vol_var=1;
            reverse=1;
            pre_next=1;


            liste = dir(strcat(cd,'\playlist\*.wav'));

                
                for k = 1 : numel(liste)
      listboxItems{k} = liste(k).name;
 
     disp(listboxItems{k});
                end
   
    app.listbox_playlist.Items=listboxItems;
    app.listbox_playlist.ItemsData=1:numel(app.listbox_playlist.Items);


        end

        % Button pushed function: ADDButton
        function create_playlist(app, event)
           playlist_create(app);
           start_program(app);

        end

        % Button pushed function: PLAYButton
        function play_select(app, event)
           global  listboxItems v reverse pre_next;
            v=app.listbox_playlist.Value;
           if pre_next==1
           
           elseif pre_next==2
               
            
           end
           
          
            play_sound(app);
            
        end

        % Button pushed function: PAUSEButton
        function pause_list(app, event)
            global player1 t;
            app.RESUMEButton.Enable = 'on';
             app.PLAYButton.Enable = 'off';
           
             pause(player1);
           
             stop(t);
        end

        % Button pushed function: RESUMEButton
        function resume_list(app, event)
            global player1 t;
            resume(player1);
           
           start(t);
          
        end

        % Value changed function: SpeedSlider, VolumeSlider
        function volume_speed(app, event)

             change_speed_volume(app);
        end

        % Callback function
        function open_advance(app, event)
            clc;
            clear all;
            closereq;
            mixing_sounds();
        end

        % Callback function
        function forcemono_left(app, event)

         x=1;
         
         force(app,x);
     
        end

        % Close request function: UIFigure
        function closefnc(app, event)
            delete(app)
            clc;
            clear all;
        end

        % Button pushed function: STOPButton
        function stop_btn(app, event)
            global player1 t vol_var reverse repeat_var;
            repeat_var=1;
            reverse=1;
            app.PAUSEButton.Enable = 'off';
            app.RESUMEButton.Enable = 'off';
            app.PLAYButton.Enable = 'on';
            app.repeat.Icon = 'dull_repaeat.png';
                app.one_lbl.Visible = 'off';
            stop(t);
            stop(player1);
             app.mute_btn.Icon = 'voll.png';
              vol_var=1;
             app.Slider.Value=0;
              app.VolumeSlider.Value=3;
              app.SpeedSlider.Value=1;
            app.STOPButton.Enable = 'off';
        end

        % Button pushed function: REMOVEButton
        function remove_from_playlist(app, event)
    audioremove_playlist(app);
        
        end

        % Callback function
        function force_right(app, event)
              x=2;
         
         force(app,x);
        end

        % Button pushed function: repeat
        function repeat_btn(app, event)
            global repeat_var;
           
        
            if repeat_var==1
                 app.repeat.Icon = 'repeat.png';
                 repeat_var=2;
                 
            elseif repeat_var==2
                
                app.one_lbl.Visible = 'on';
                repeat_var=3;
                
            elseif repeat_var==3
                app.repeat.Icon = 'dull_repaeat.png';
                app.one_lbl.Visible = 'off';
                repeat_var=1;
                
            end
        end

        % Button pushed function: mute_btn
        function mute(app, event)
             global vol_var;
           
            
            if vol_var==1
                 app.mute_btn.Icon = 'mute.png';
                  vol_var=2;
                 app.VolumeSlider.Value=0;
                 change_speed_volume(app);
                
                 
            elseif vol_var==2
                app.mute_btn.Icon = 'voll.png';
                vol_var=1;
                app.VolumeSlider.Value=3;
                change_speed_volume(app);
                
                
            end
        end

        % Button pushed function: ReverseAudioButton
        function reverse_audio(app, event)
            global reverse t;
            reverse=2;
            
            stop(t);
            play_select(app);
          
        end

        % Button pushed function: Button
        function previous(app, event)
           global pre_next v t;
            app.listbox_playlist.Value = v-1;
            pre_next=1;
            stop(t);
             play_select(app)
        end

        % Button pushed function: Button_2
        function next(app, event)
            global pre_next v t;
            app.listbox_playlist.Value = v+1;
            pre_next=2;
            stop(t);
             play_select(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closefnc, true);
            app.CurrentPlaylistPanel = uipanel(app.UIFigure);
            app.CurrentPlaylistPanel.Title = 'Current Playlist';
            app.listbox_playlist = uilistbox(app.CurrentPlaylistPanel);
            app.listbox_playlist.Items = {};
            app.listbox_playlist.Value = {};
            app.ADDButton = uibutton(app.CurrentPlaylistPanel, 'push');
            app.ADDButton.ButtonPushedFcn = createCallbackFcn(app, @create_playlist, true);
            app.ADDButton.Icon = 'add.png';
            app.ADDButton.Text = 'ADD ';
            app.REMOVEButton = uibutton(app.CurrentPlaylistPanel, 'push');
            app.REMOVEButton.ButtonPushedFcn = createCallbackFcn(app, @remove_from_playlist, true);
            app.REMOVEButton.Icon = 'minus.png';
            app.REMOVEButton.Text = 'REMOVE ';
            app.SongNamesLabel = uilabel(app.CurrentPlaylistPanel);
            app.SongNamesLabel.Text = ' > Song Names';
            app.MusicPlayerPanel = uipanel(app.UIFigure);
            app.MusicPlayerPanel.Title = 'Music Player';
            app.img = uiaxes(app.MusicPlayerPanel);
            app.PAUSEButton = uibutton(app.MusicPlayerPanel, 'push');
            app.PAUSEButton.ButtonPushedFcn = createCallbackFcn(app, @pause_list, true);
            app.PAUSEButton.Icon = 'pausee.png';
            app.PAUSEButton.Enable = 'off';
            app.PAUSEButton.Text = 'PAUSE';
            app.PLAYButton = uibutton(app.MusicPlayerPanel, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @play_select, true);
            app.PLAYButton.Icon = 'playyy.png';
            app.PLAYButton.Text = 'PLAY';
            app.RESUMEButton = uibutton(app.MusicPlayerPanel, 'push');
            app.RESUMEButton.ButtonPushedFcn = createCallbackFcn(app, @resume_list, true);
            app.RESUMEButton.Icon = 'resumee.png';
            app.RESUMEButton.Enable = 'off';
            app.RESUMEButton.Text = 'RESUME';
            app.SpeedSliderLabel = uilabel(app.MusicPlayerPanel);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.MusicPlayerPanel);
            app.SpeedSlider.Limits = [0 4];
            app.SpeedSlider.MajorTicks = [0 0.4 0.8 1.2 1.6 2 2.4 2.8 3.2 3.6 4];
            app.SpeedSlider.ValueChangedFcn = createCallbackFcn(app, @volume_speed, true);
            app.SpeedSlider.Value = 1;
            app.Slider_2Label = uilabel(app.MusicPlayerPanel);
            app.Slider_2Label.Text = '';
            app.VolumeSlider = uislider(app.MusicPlayerPanel);
            app.VolumeSlider.Limits = [0 10];
            app.VolumeSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @volume_speed, true);
            app.VolumeSlider.Value = 3;
            app.image_xs = uiaxes(app.MusicPlayerPanel);
            app.DurationSliderLabel = uilabel(app.MusicPlayerPanel);
            app.DurationSliderLabel.Text = 'Duration';
            app.Slider = uislider(app.MusicPlayerPanel);
            app.Slider.Limits = [0 500];
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {};
            app.Slider.MinorTicks = [];
            app.STOPButton = uibutton(app.MusicPlayerPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @stop_btn, true);
            app.STOPButton.Icon = 'stop.png';
            app.STOPButton.Enable = 'off';
            app.STOPButton.Text = 'STOP';
            app.image_xs_2 = uiaxes(app.MusicPlayerPanel);
            app.repeat = uibutton(app.MusicPlayerPanel, 'push');
            app.repeat.ButtonPushedFcn = createCallbackFcn(app, @repeat_btn, true);
            app.repeat.Icon = 'dull_repaeat.png';
            app.repeat.Text = '';
            app.mute_btn = uibutton(app.MusicPlayerPanel, 'push');
            app.mute_btn.ButtonPushedFcn = createCallbackFcn(app, @mute, true);
            app.mute_btn.Icon = 'voll.png';
            app.mute_btn.Text = '';
            app.one_lbl = uilabel(app.MusicPlayerPanel);
            app.one_lbl.Visible = 'off';
            app.one_lbl.Text = '1';
            app.Button = uibutton(app.MusicPlayerPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @previous, true);
            app.Button.Icon = 'left.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.MusicPlayerPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @next, true);
            app.Button_2.Icon = 'right.png';
            app.Button_2.Text = '';
            app.AdvanceOptionsPanel = uipanel(app.UIFigure);
            app.AdvanceOptionsPanel.Title = 'Advance Options';
            app.ReverseAudioButton = uibutton(app.AdvanceOptionsPanel, 'push');
            app.ReverseAudioButton.ButtonPushedFcn = createCallbackFcn(app, @reverse_audio, true);
            app.ReverseAudioButton.Text = 'Reverse Audio';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.Text = 'Exit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = playlist_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @start_program)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end